classdef analyze_IRcamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        FileOpenMenu       matlab.ui.container.Menu
        CaxisMinEditField  matlab.ui.control.NumericEditField
        colorbarminLabel   matlab.ui.control.Label
        CaxisMaxEditField  matlab.ui.control.NumericEditField
        colorbarmaxLabel   matlab.ui.control.Label
        MainDataDropDown   matlab.ui.control.DropDown
        MainDataLabel      matlab.ui.control.Label
        MainImageUIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataStruct % すべてのデータを格納する構造体
        MainData % 表示するデータ
        MainImage % MainImageUIAxesで表示している画像データ
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileOpenMenu
        function FileOpenMenuSelected(app, event)
            data = load_ir_data;
            if size(data,1)==0
                return
            end
            app.DataStruct = data;
            itemsData = struct2cell(app.DataStruct);
            app.MainData = itemsData{1,1};
            % 各種コンポーネントの設定
            app.MainDataDropDown.Items = fieldnames(app.DataStruct); % DropDownのラベル
            app.MainDataDropDown.ItemsData = struct2cell(app.DataStruct); % DropDownの実データ
            app.MainImage = imagesc(app.MainImageUIAxes, app.MainData(:,:,1));
            colorbar(app.MainImageUIAxes)
            caxis(app.MainImageUIAxes, ...
                [min(app.MainImage.CData,[],'all'), max(app.MainImage.CData,[],'all')])
            app.CaxisMaxEditField.Value = max(app.MainImage.CData,[],'all');
            app.CaxisMinEditField.Value = min(app.MainImage.CData,[],'all');
        end

        % Value changed function: CaxisMaxEditField
        function CaxisMaxEditFieldValueChanged(app, event)
            value = app.CaxisMaxEditField.Value;
            if ~(value > app.CaxisMinEditField.Value)
                msgbox("カラーバーの上限は下限より大きい値である必要があります")
                return
            end
            caxis(app.MainImageUIAxes, [app.CaxisMinEditField.Value, value])
        end

        % Value changed function: CaxisMinEditField
        function CaxisMinEditFieldValueChanged(app, event)
            value = app.CaxisMinEditField.Value;
            if ~(value < app.CaxisMaxEditField.Value)
                msgbox("カラーバーの下限は上限より小さい値である必要があります")
                return
            end
            caxis(app.MainImageUIAxes, [value, app.CaxisMaxEditField.Value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'ファイル';
            app.FileOpenMenu = uimenu(app.FileMenu);
            app.FileOpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpenMenuSelected, true);
            app.FileOpenMenu.Accelerator = 'O';
            app.FileOpenMenu.Text = '開く';
            app.MainImageUIAxes = uiaxes(app.UIFigure);
            title(app.MainImageUIAxes, 'Title')
            xlabel(app.MainImageUIAxes, 'X')
            ylabel(app.MainImageUIAxes, 'Y')
            zlabel(app.MainImageUIAxes, 'Z')
            app.MainImageUIAxes.XLim = [0 640];
            app.MainImageUIAxes.YLim = [0 512];
            colormap(app.MainImageUIAxes, 'jet')
            app.MainDataLabel = uilabel(app.UIFigure);
            app.MainDataLabel.Text = '表示するデータ';
            app.MainDataDropDown = uidropdown(app.UIFigure);
            app.MainDataDropDown.Items = {};
            app.MainDataDropDown.Value = {};
            app.colorbarmaxLabel = uilabel(app.UIFigure);
            app.colorbarmaxLabel.Text = 'colorbar max';
            app.CaxisMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaxisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CaxisMaxEditFieldValueChanged, true);
            app.colorbarminLabel = uilabel(app.UIFigure);
            app.colorbarminLabel.Text = 'colorbar min';
            app.CaxisMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaxisMinEditField.ValueChangedFcn = createCallbackFcn(app, @CaxisMinEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyze_IRcamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end